classdef SetCOMs < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        SetCOMsPanel                   matlab.ui.container.Panel
        DropDownForceRight             matlab.ui.control.DropDown
        DropDownForceLeft              matlab.ui.control.DropDown
        DropDownInclBack               matlab.ui.control.DropDown
        DropDownInclFront              matlab.ui.control.DropDown
        DropDownArduino                matlab.ui.control.DropDown
        ClearCOMPortsButton            matlab.ui.control.Button
        SetForceGaugeRightCOMButton    matlab.ui.control.Button
        SetForceGaugeLeftCOMButton     matlab.ui.control.Button
        SetInclinomterBackCOMButton    matlab.ui.control.Button
        SetInclinometerFrontCOMButton  matlab.ui.control.Button
        SetArduinoCOMButton            matlab.ui.control.Button
    end

    
    methods (Access = public)
        
        function results = setSerial(serial, port, baud)
            try
               clear serial;
               serial = serialport(port, baud);
            catch e
               str = strcat("failed to connect serial. \n Exception: \n ", e);
               fig = uifigure;
               uialert(fig,str,'Serial Port Connection Error');
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Drop down opening function: DropDownArduino, DropDownForceLeft, 
        % ...and 3 other components
        function DropDownArduinoOpening(comp, event)
            % set all drop down options to avalible com ports
            freeports = serialportlist("available");
            disp(freeports);
            app.DropDownArduino.Items = freeports;
        end

        % Button pushed function: SetArduinoCOMButton
        function SetArduinoCOMButtonPushed(comp, event)
            global arduino_serail;
            %get selected item from arduino dropdown
            selectedCOM = comp.DropDownArduino.Value;
            %check if not default option
            if selectedCOM == comp.DropDownArduino.Placeholder
                %error message
               str = strcat("COM not selected. \n Selected: \n ", selectedCOM);
               fig = uifigure;
               uialert(fig,str,'Serial Port Connection Error');
            end
            setSerial(arduino_serail, selectedCOM, 115200);
        end

        % Button pushed function: SetInclinometerFrontCOMButton
        function SetInclinometerFrontCOMButtonPushed(comp, event)
            global inclinometer_front_serial;
            %get selected item from arduino dropdown
            selectedCOM = comp.DropDownInclFront.Value;
            %check if not default option
            if selectedCOM == comp.DropDownInclFront.Placeholder
               %error message
               str = strcat("COM not selected. \n Selected: \n ", selectedCOM);
               fig = uifigure;
               uialert(fig,str,'Serial Port Connection Error');
            end
            setSerial(inclinometer_front_serial, selectedCOM, 9600);
        end

        % Button pushed function: SetInclinomterBackCOMButton
        function SetInclinomterBackCOMButtonPushed(comp, event)
            global inclinometer_back_serial;
            %get selected item from arduino dropdown
            selectedCOM = comp.DropDownInclBack.Value;
            %check if not default option
            if selectedCOM == comp.DropDownInclBack.Placeholder
               %error message
               str = strcat("COM not selected. \n Selected: \n ", selectedCOM);
               fig = uifigure;
               uialert(fig,str,'Serial Port Connection Error');
            end
            setSerial(inclinometer_back_serial, selectedCOM, 9600);
        end

        % Button pushed function: SetForceGaugeLeftCOMButton
        function SetForceGaugeLeftCOMButtonPushed(comp, event)
            global force_gage_left_serial;
            %get selected item from arduino dropdown
            selectedCOM = comp.DropDownForceLeft.Value;
            %check if not default option
            if selectedCOM == comp.DropDownForceLeft.Placeholder
               %error message
               str = strcat("COM not selected. \n Selected: \n ", selectedCOM);
               fig = uifigure;
               uialert(fig,str,'Serial Port Connection Error');
            end
            setSerial(force_gage_left_serial, selectedCOM, 9600);
        end

        % Button pushed function: SetForceGaugeRightCOMButton
        function SetForceGaugeRightCOMButtonPushed(comp, event)
            global force_gage_right_serial;
            %get selected item from arduino dropdown
            selectedCOM = comp.DropDownForceRight.Value;
            %check if not default option
            if selectedCOM == comp.DropDownForceRight.Placeholder
               %error message
               str = strcat("COM not selected. \n Selected: \n ", selectedCOM);
               fig = uifigure;
               uialert(fig,str,'Serial Port Connection Error');
            end
            setSerial(force_gage_right_serial, selectedCOM, 9600);
        end

        % Button pushed function: ClearCOMPortsButton
        function ClearCOMPortsButtonPushed(comp, event)
            global ardiuno_serial inclinometer_front_serial inclinometer_back_serial force_gage_left_serial force_gage_right_serial;
            clear ardiuno_serial inclinometer_front_serial inclinometer_back_serial force_gage_left_serial force_gage_right_serial;
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 345 247];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.SetCOMsPanel = uipanel(comp);
            comp.SetCOMsPanel.Title = 'Set COMs';
            comp.SetArduinoCOMButton = uibutton(comp.SetCOMsPanel, 'push');
            comp.SetArduinoCOMButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SetArduinoCOMButtonPushed, true);
            comp.SetArduinoCOMButton.Text = 'Set Arduino COM';
            comp.SetInclinometerFrontCOMButton = uibutton(comp.SetCOMsPanel, 'push');
            comp.SetInclinometerFrontCOMButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SetInclinometerFrontCOMButtonPushed, true);
            comp.SetInclinometerFrontCOMButton.Text = 'Set Inclinometer Front COM';
            comp.SetInclinomterBackCOMButton = uibutton(comp.SetCOMsPanel, 'push');
            comp.SetInclinomterBackCOMButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SetInclinomterBackCOMButtonPushed, true);
            comp.SetInclinomterBackCOMButton.Text = 'Set Inclinomter Back COM';
            comp.SetForceGaugeLeftCOMButton = uibutton(comp.SetCOMsPanel, 'push');
            comp.SetForceGaugeLeftCOMButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SetForceGaugeLeftCOMButtonPushed, true);
            comp.SetForceGaugeLeftCOMButton.Text = 'Set Force Gauge Left COM';
            comp.SetForceGaugeRightCOMButton = uibutton(comp.SetCOMsPanel, 'push');
            comp.SetForceGaugeRightCOMButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SetForceGaugeRightCOMButtonPushed, true);
            comp.SetForceGaugeRightCOMButton.Text = 'Set Force Gauge Right COM';
            comp.ClearCOMPortsButton = uibutton(comp.SetCOMsPanel, 'push');
            comp.ClearCOMPortsButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ClearCOMPortsButtonPushed, true);
            comp.ClearCOMPortsButton.Text = 'Clear COM Ports';
            comp.DropDownArduino = uidropdown(comp.SetCOMsPanel);
            comp.DropDownArduino.Items = {'Options'};
            comp.DropDownArduino.DropDownOpeningFcn = matlab.apps.createCallbackFcn(comp, @DropDownArduinoOpening, true);
            comp.DropDownArduino.Placeholder = 'Options';
            comp.DropDownArduino.Value = 'Options';
            comp.DropDownInclFront = uidropdown(comp.SetCOMsPanel);
            comp.DropDownInclFront.Items = {'Options'};
            comp.DropDownInclFront.DropDownOpeningFcn = matlab.apps.createCallbackFcn(comp, @DropDownArduinoOpening, true);
            comp.DropDownInclFront.Placeholder = 'Options';
            comp.DropDownInclFront.Value = 'Options';
            comp.DropDownInclBack = uidropdown(comp.SetCOMsPanel);
            comp.DropDownInclBack.Items = {'Options'};
            comp.DropDownInclBack.DropDownOpeningFcn = matlab.apps.createCallbackFcn(comp, @DropDownArduinoOpening, true);
            comp.DropDownInclBack.Placeholder = 'Options';
            comp.DropDownInclBack.Value = 'Options';
            comp.DropDownForceLeft = uidropdown(comp.SetCOMsPanel);
            comp.DropDownForceLeft.Items = {'Options'};
            comp.DropDownForceLeft.DropDownOpeningFcn = matlab.apps.createCallbackFcn(comp, @DropDownArduinoOpening, true);
            comp.DropDownForceLeft.Placeholder = 'Options';
            comp.DropDownForceLeft.Value = 'Options';
            comp.DropDownForceRight = uidropdown(comp.SetCOMsPanel);
            comp.DropDownForceRight.Items = {'Options'};
            comp.DropDownForceRight.DropDownOpeningFcn = matlab.apps.createCallbackFcn(comp, @DropDownArduinoOpening, true);
            comp.DropDownForceRight.Placeholder = 'Options';
            comp.DropDownForceRight.Value = 'Options';
        end
    end
end